classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ForceStopButton  matlab.ui.control.Button
        StartButton      matlab.ui.control.Button
        UIAxes_2         matlab.ui.control.UIAxes
        UIAxes           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function Start(app, event)
            clear; clc; clf; close all;
            a = arduino("/dev/tty.usbserial-AQ02T0FB", "Nano3", "Libraries", 'Ultrasonic');
            ultrasonicObj = ultrasonic(a, "D3", "D2");

            r = [];
            r_max = 0.2;
            vertical_speed = 0.1;
            angular_speed = pi/6;
            t_max = 60;
            for trial = 0 : 0.04 : t_max
                data = readDistance(ultrasonicObj);
                if data > r_max
                    data = 0;
                else
                    data = r_max - data;
                end
                r = [r data];
                pause(0.04)
                
            end
            trial = size(0 : 0.04 : t_max);
            t = 0 : 0.04 : t_max;
            theta = t.*angular_speed;
            SmoothData = smoothdata(r, 'movmean', 50);
            x = SmoothData.*cos(theta);
            y = SmoothData.*sin(theta);
            z = t.*vertical_speed;
            scatter3(app.UIAxes, x, y, z);
            d = ceil(2*pi/angular_speed/0.04);
            X = [x(1:trial-d)
                x(d+1:trial)];
            Y = [y(1:trial-d)
                y(d+1:trial)];
            Z = [z(1:trial-d)
                z(d+1:trial)];
            surf(app.UIAxes_2, X, Y, Z);
        end

        % Button pushed function: ForceStopButton
        function ForceStop(app, event)
            clear ultrasonicObj;
            trial = size(r);
            trial = trial(1);
            t_max = 0.04*(trial-1);
            t = 0 : 0.04 : t_max;
            theta = t.*angular_speed;
            SmoothData = smoothdata(r, 'movmean', 50);
            x = SmoothData.*cos(theta);
            y = SmoothData.*sin(theta);
            z = t.*vertical_speed;
            scatter3(app.UIAxes, x, y, z);
            d = ceil(2*pi/angular_speed/0.04);
            X = [x(1:trial-d)
                x(d+1:trial)];
            Y = [y(1:trial-d)
                y(d+1:trial)];
            Z = [z(1:trial-d)
                z(d+1:trial)];
            surf(app.UIAxes_2, X, Y, Z);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Scatter Plot')
            xlabel(app.UIAxes, 'x[m]')
            ylabel(app.UIAxes, 'y[m]')
            zlabel(app.UIAxes, 'z[m]')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Surface Plot')
            xlabel(app.UIAxes_2, 'x[m]')
            ylabel(app.UIAxes_2, 'y[m]')
            zlabel(app.UIAxes_2, 'z[m]')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @Start, true);
            app.StartButton.Text = 'Start';
            app.ForceStopButton = uibutton(app.UIFigure, 'push');
            app.ForceStopButton.ButtonPushedFcn = createCallbackFcn(app, @ForceStop, true);
            app.ForceStopButton.Text = 'Force Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end